classdef Brain_Tumour_Detection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UIAxes                matlab.ui.control.UIAxes
        SelectMRIImageButton  matlab.ui.control.Button
        TumourAnalysisButton  matlab.ui.control.Button
    end

    methods (Access = private)

        % Button pushed function: SelectMRIImageButton
        function SelectMRIImageButtonPushed(app, event)
            startingFolder = 'C:\';
            if ~exist(startingFolder, 'dir')
                %If folder doesn't exist, start in current folder
                startingFolder = pwd;
            end
            
            defaultFileName = fullfile(startingFolder, '*.*');
            [baseFileName, folder] = uigetfile(defaultFileName, 'Select file');
            if baseFileName == 0
                %User clicked cancel
                return;
            end
            fullFileName = fullfile(folder, baseFileName);
            global brainMRIimg= imread(fullFileName);
            imshow(brainMRIimg, 'parent', app.UIAxes);
        end

        % Button pushed function: TumourAnalysisButton
        function tumourAnalysisBegin(app, event)
            img = brainMRIimg;
            bw = rgb2gray(img);
            label = bwlabel(bw);

            stats = regionprops(label, 'Solidity', 'Area');

            density = [stats.Solidity];
            area = [stats.Area];
            
            high_dense_area = density > 0.5;
            max_area = max(area(high_dense_area));
            tumour_label = find(area == max_area);
            tumour = ismember(label, tumour_label);
            
            se = strel('square', 5);
            tumour = imdilate(tumour,se);
            
            figure(2)
            subplot (1,3,1)
            imshow(img,[])
            title('brain')
            
            subplot (1,3,2)
            imshow(tumour, [])
            title('Tumour segmented')
            
            [B,L] = bwboundaries(tumour, 'noholes')
            subplot(1,3,3)
            imshow(img,[])
            hold on
            for i = 1:length(B)
                plot(B{i}(:,2), B{i}(:,1), 'y', 'linewidth', 1.45)
            end
            title('Detected Tumours')
            hold off
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'MRI Original Image')
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.SelectMRIImageButton = uibutton(app.UIFigure, 'push');
            app.SelectMRIImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectMRIImageButtonPushed, true);
            app.SelectMRIImageButton.Text = 'Select MRI Image';
            app.TumourAnalysisButton = uibutton(app.UIFigure, 'push');
            app.TumourAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @tumourAnalysisBegin, true);
            app.TumourAnalysisButton.Text = 'Tumour Analysis';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Brain_Tumour_Detection

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end