classdef Brain_Tumour_Detection_and_Classification < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        BrainTumourDetectionandClassificationLabel  matlab.ui.control.Label
        LoadImageButton                matlab.ui.control.Button
        DetectandAnalyseTumoursButton  matlab.ui.control.Button
        UIAxes3                        matlab.ui.control.UIAxes
    end

    methods (Access = private)

        % Button pushed function: LoadImageButton
        function loadImage(app, event)
            startingLocation = 'C:\';
            global brainMRIimg
            if ~exist(startingLocation, 'dir')
                %If folder doesn't exist, start in current folder
                startingLocation = pwd;
            end
            
            defaultFileName = fullfile(startingLocation, '*.*');
            [baseFileName, folder] = uigetfile(defaultFileName, 'Select file');
            if baseFileName == 0
                %User clicked cancel
                return;
            end
            fullFileName = fullfile(folder, baseFileName);
            brainMRIimg= imread(fullFileName);
            %brainMRIimg = im2double(brainMRIimg);
            imshow(brainMRIimg, 'parent', app.UIAxes);
        end

        % Button pushed function: DetectandAnalyseTumoursButton
        function detectionAnalysisStart(app, event)
            global brainMRIimg
            img = brainMRIimg;
            imshow(img, 'parent', app.UIAxes2);
            bw = im2bw(img, 0.7);
            label = bwlabel(bw);

            stats = regionprops(label, 'Solidity', 'Area');

            density = [stats.Solidity];
            area = [stats.Area];
            
            high_dense_area = density > 0.5;
            max_area = max(area(high_dense_area));
            tumour_label = find(area == max_area);
            tumour = ismember(label, tumour_label);
            
            se = strel('square', 5);
            tumour = imdilate(tumour,se);
            imshow(tumour, 'parent', app.UIAxes3)
            
            [B,~] = bwboundaries(tumour, 'noholes');
            %imshow(img, 'parent', app.UIAxes2);
            hold on
            visboundaries(app.UIAxes2, B);
            %for i = 1:length(B)
             %   plot(app.UIAxes2,B{i}(:,2), B{i}(:,1), 'y', 'linewidth', 1.45);
            %end
            %hold off
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Output Image')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.BrainTumourDetectionandClassificationLabel = uilabel(app.UIFigure);
            app.BrainTumourDetectionandClassificationLabel.Text = 'Brain Tumour Detection and Classification';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @loadImage, true);
            app.LoadImageButton.Text = 'Load Image';
            app.DetectandAnalyseTumoursButton = uibutton(app.UIFigure, 'push');
            app.DetectandAnalyseTumoursButton.ButtonPushedFcn = createCallbackFcn(app, @detectionAnalysisStart, true);
            app.DetectandAnalyseTumoursButton.Text = 'Detect and Analyse Tumours';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Isolated Tumour')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes3.ZTick = [];
        end
    end

    methods (Access = public)

        % Construct app
        function app = Brain_Tumour_Detection_and_Classification

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end